classdef scriptedit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScriptsUIFigure                 matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SaveAsMenu                      matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        EventHistoryTextAreaLabel       matlab.ui.control.Label
        EventHistoryTextArea            matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        ImplantTab                      matlab.ui.container.Tab
        StartScriptButton               matlab.ui.control.Button
        ScriptsEnabledSwitchLabel       matlab.ui.control.Label
        ScriptsEnabledSwitch            matlab.ui.control.Switch
        ScriptTable                     matlab.ui.control.Table
        CheckBox                        matlab.ui.control.CheckBox
        ScriptFlashTable                matlab.ui.control.Table
        RefreshFlashButton              matlab.ui.control.Button
        RefreshButton                   matlab.ui.control.Button
        StopScriptButton                matlab.ui.control.Button
        RunScriptOnceButton             matlab.ui.control.Button
        RunScriptTimedButton            matlab.ui.control.Button
        CheckStatusButton               matlab.ui.control.Button
        DeleteScriptButton              matlab.ui.control.Button
        ScriptExecutionPeriodEditFieldLabel  matlab.ui.control.Label
        ScriptExecutionPeriodEditField  matlab.ui.control.NumericEditField
        msLabel                         matlab.ui.control.Label
        RefreshButton_2                 matlab.ui.control.Button
        PDOTimerTriggeredScriptsButton  matlab.ui.control.Button
        ScriptLibraryTab                matlab.ui.container.Tab
        DownloadButton                  matlab.ui.control.Button
        AsssembleButton                 matlab.ui.control.Button
        LibraryTable                    matlab.ui.control.Table
        EditButton                      matlab.ui.control.Button
        AddExistingButton               matlab.ui.control.Button
        DeleteButton                    matlab.ui.control.Button
        AddNewButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        nnp = []; % Description
        scripts = [];
        activeRow = [];
    end
    
    methods (Access = private)
        function populateFileTable(app)
            nScripts = length(app.scripts.library);
            
            data = cell(nScripts,7);
            for i=1:nScripts
                sp = find(cellfun(@(x) x.id == i, app.scripts.active));
                if length(sp)>1
                    error('more than one script shares script pointer')
                elseif length(sp)==1
                    data{i,2}=sp;
                else
                    data{i,2} = '-';
                end
                data{i,1}=i;
                if ~isempty(app.scripts.library{i})
                    data{i,3}=app.scripts.library{i}.name;
                    data{i,4}=app.scripts.library{i}.path;
                    data{i,5}=length(app.scripts.library{i}.download);
                    data{i,6}=app.scripts.library{i}.assembled;
                    list = dir(app.scripts.library{i}.path);
                    data{i,7}=list.date;
                end
            end
            
            app.LibraryTable.Data = data;
            app.LibraryTable.ColumnFormat = {'numeric', 'numeric','char','char'}; %right align
        end
    
        function populateFlashTable(app)
            %Takes ~6minutes to read entire script space using readMemory, so instead just read length and ID from each
            startAddress = hex2dec('30000');
            scriptSize = 2048;
            scriptSizeLong = 7680;
            
            data = cell(25,2);
            bgColor = repmat([.7, .7, .7],25,1);
            
            app.ScriptFlashTable.ColumnFormat = {'numeric', 'numeric'}; %right align
            for i=1:25
                addr = startAddress + (i-1)*scriptSize;
                resp = readmem(app.nnp, 7, 'flash', addr, 2, false);
                if length(resp)==2
                    scriptLen = double(typecast(resp, 'uint16'));
                    
                    if (i<25 && scriptLen<scriptSize) || scriptLen < scriptSizeLong
                        resp = app.nnp.readMemory(7, 'flash', addr+scriptLen-1, 1,false);
                        if length(resp) == 1
                            scriptID = resp;
                            bgColor(i,:) = [1 1 1];
                        else
                            scriptID = '?';
                            bgColor(i,:) = [1 1 0];
                        end
                    elseif scriptLen == 65535
                        scriptLen = '-';
                        scriptID = '-';
                        bgColor(i,:) = [.95 .95 .95];
                    else
                        scriptLen = [num2str(scriptLen) '?'];
                        scriptID = '?';
                        bgColor(i,:) = [1 0 0];
                    end
                else
                    scriptLen = '?';
                    scriptID = '?';
                    bgColor(i,:) = [1 0.5 0];
                end
                data{i,1} = scriptID;
                data{i,2} = scriptLen;
                app.ScriptFlashTable.Data = data;
                app.ScriptFlashTable.BackgroundColor = bgColor;
            end
        end
    
        function populateTable(app)
            %Can only read 50 bytes at a time so no more than 12 script controls
            resp = app.nnp.read(7, '1f51', 1,12, 'uint32'); %read script 1-12 controls
            if length(resp) ~= 12
                app.RefreshButton.BackgroundColor = [1 0 0];
                return
            else
                scriptControl(1:12) = double(resp);
            end
            resp = app.nnp.read(7, '1f51', 13,12, 'uint32'); %read script 13-24 controls
            if length(resp) ~= 12    
                app.RefreshButton.BackgroundColor = [1 0 0];
                return
            else
                scriptControl(13:24) = double(resp);
            end
            resp =  app.nnp.read(7, '1f51', 25,1, 'uint32'); %read script 25 controls
            if length(resp) ~= 1
                app.RefreshButton.BackgroundColor = [1 0 0];
                return
            else
                scriptControl(25) = double(resp);
            end
            
            resp = app.nnp.read(7, '1f56', 1, 'uint8'); %read script order
            if length(resp) ~= 25
                app.RefreshButton.BackgroundColor = [1 0 0];
                return
            else
                scriptOrder = double(resp);
            end
            data = cell(25,7);
            % bit  0   : 1=run 
            % bit  1   : 1=run continuous, 0=halt
            % bit  2   : 1 = reinit global var table
            % bit  3-7 : reserved for future use
            % bit  8-15: script ID
            % bit 16-23: reserved for future use
            % bit 24-30: error code
            % bit 31   : 1= run once and successful execution
            
            % green: if run continuous (run = 2)
            % yellow: run once (run = 1)
            % red: error
            % white: stopped
            % gray: unused
            
            bgColor = zeros(25,3);
            for i=1:25
                scriptID = bitshift(bitand(scriptControl(i), hex2dec('FF00')), -8);
                run = bitand(scriptControl(i), hex2dec('03'));
                disp(run)
                errorCode = bitshift(bitand(scriptControl(i), hex2dec('7F000000')), -24);
                runOnceResult = bitget(scriptControl(i),32);
                if scriptID == 0 
                    bgColor(i,:) = [.95 .95 .95];
                    data{i,1} = [];
                else
                    if errorCode
                        bgColor(i,:) = [1 0 0];
                    else
                        if run==2
                            bgColor(i,:) = [0 1 0];
                        elseif run == 1 && runOnceResult == 1
                            bgColor(i,:) = [1 1 0];
                        elseif run>0
                            bgColor(i,:) = [1 0.5 0];
                        else 
                            bgColor(i,:) = [1 1 1];
                        end
                    end
                    
                    data{i,5} = errorCode;
                    data{i,2} = scriptID;
                    data{i,1} = true;
                end
                
                data{i,4} = scriptOrder(i);
            end
            
            app.ScriptTable.Data = data;
            app.ScriptTable.BackgroundColor = bgColor;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, NNP)
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            
            app.scripts.library = cell(2,1);
            app.scripts.library{1} = struct('name', 'trunkramp', 'path', 'C:\Users\Joris\Documents\Repositories\NNP\matlabapi\trunk\apps\Scripting\trunkramp.nnpscript', 'download', [], 'assembled', [],'notes', []);
            app.scripts.library{2} = struct('name', 'demo', 'path', 'C:\Users\Joris\Documents\Repositories\NNP\matlabapi\trunk\apps\Scripting\demo.nnpscript', 'download', [], 'assembled', [],'notes', []);
            app.scripts.active = cell(25,1);
            app.scripts.active{1} = struct('id', 1, 'order', 1);
            app.scripts.active{2} = struct('id', 2, 'order', 2);
            for j=3:25
                app.scripts.active{j} = struct('id', 0, 'order', 0);
            end
            app.populateTable();
            app.ScriptFlashTable.Data = cell(25,2);
            app.ScriptFlashTable.BackgroundColor = [.7, .7, .7];
            app.populateFileTable();
            %app.populateFlashTable();
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.populateTable();
        end

        % Button pushed function: AsssembleButton
        function AsssembleButtonPushed(app, event)
            file = app.LibraryTable.Data{app.activeRow,4};
            id = app.LibraryTable.Data{app.activeRow,1};
            download = assembler(id, file);
            app.scripts.library{app.activeRow}.download = download;
            
            app.LibraryTable.Data{app.activeRow,6} = char(datetime);
            app.LibraryTable.Data{app.activeRow,5} = length(download);
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)

            check = cell2mat(app.ScriptTable.Data(:,1));
            if all(check)
                app.CheckBox.Value = false;
                check(:) = false;
            else
                app.CheckBox.Value = true;
                check(:) = true;
            end
            
            app.ScriptTable.Data(:,1) = mat2cell(check, ones(length(check),1));
        end

        % Button pushed function: RefreshFlashButton
        function RefreshFlashButtonPushed(app, event)
            app.populateFlashTable();
        end

        % Cell edit callback: ScriptTable
        function ScriptTableCellEdit(app, event)
            col = event.Indices(2);
            row = event.Indices(1);
            newData = event.NewData;
            oldData = event.PreviousData;
            

            if col == 4
                if isnan(newData) || newData < 0 || newData > 25
                    app.ScriptTable.Data(row, col) = oldData;
                    return
                end
                scriptOrder = cell2mat(app.ScriptTable.Data(:,4))';
                if newData == 0 
                     scriptOrder(row) = 0;
                     app.ScriptTable.Data{row, col} = 0;
                else
                    scriptOrder = cell2mat(app.ScriptTable.Data(:,4))';
                    i = find(scriptOrder==newData);
                    i(i==row) = [];
                    if ~isempty(i) 
                        userResp = questdlg('Two scripts should not have same order value.  Do you want to swap order with other script, zero other script, or cancel?', 'Script Order', 'Swap', 'Zero', 'Cancel','Cancel');
                        switch userResp
                            case 'Swap'
                                scriptOrder(i) = oldData;
                                app.ScriptTable.Data{i, col} = oldData;
                            case 'Zero'
                                scriptOrder(i) = 0;
                                app.ScriptTable.Data{i, col} = 0;
                            otherwise
                                scriptOrder(row) = oldData;
                                app.ScriptTable.Data{row, col} = oldData;
                                return
                        end
                    end
                end
                resp = app.nnp.write(7, '1f56', 1, scriptOrder, 'uint8'); %write script order
                if ~isequal(resp, 0)
                    msgbox('write script order may have failed, hit refresh table to read back')
                end
            end
            
            
        end

        % Cell selection callback: LibraryTable
        function LibraryTableCellSelection(app, event)
            app.activeRow = event.Indices(1);
        end

        % Button pushed function: DownloadButton
        function DownloadButtonPushed(app, event)
            sp = app.LibraryTable.Data{app.activeRow,2};
            download = app.scripts.library{app.activeRow}.download;
            if length(download) < 10
                if isempty(download)
                    msgbox('assemble script first')
                else
                    msgbox('invalid short download')
                end
            end
            loadscript(app.nnp, sp,download);
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            winopen(app.LibraryTable{app.activeRow,4})
        end

        % Button pushed function: AddExistingButton
        function AddExistingButtonPushed(app, event)
            [filename, pathname ] = uigetfile('*.nnpscript', 'Choose Script File');
            if filename == 0 
                return
            else
                file = [pathname filename];
            end
            %TODO: find next available script ID, and assign new path filename
        end

        % Button pushed function: AddNewButton
        function AddNewButtonPushed(app, event)
             
            %TODO: create next nnpscript file, find next available script ID, and assign new path filename
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScriptsUIFigure = uifigure('Visible', 'off');
            app.ScriptsUIFigure.Name = 'Scripts';
            app.FileMenu = uimenu(app.ScriptsUIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.Text = 'Save As';
            app.HelpMenu = uimenu(app.ScriptsUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.EventHistoryTextAreaLabel = uilabel(app.ScriptsUIFigure);
            app.EventHistoryTextAreaLabel.Text = 'Event History';
            app.EventHistoryTextArea = uitextarea(app.ScriptsUIFigure);
            app.TabGroup = uitabgroup(app.ScriptsUIFigure);
            app.ImplantTab = uitab(app.TabGroup);
            app.ImplantTab.Title = 'Implant';
            app.StartScriptButton = uibutton(app.ImplantTab, 'push');
            app.StartScriptButton.Text = 'Start Script';
            app.ScriptsEnabledSwitchLabel = uilabel(app.ImplantTab);
            app.ScriptsEnabledSwitchLabel.Text = 'Scripts Enabled';
            app.ScriptsEnabledSwitch = uiswitch(app.ImplantTab, 'slider');
            app.ScriptTable = uitable(app.ImplantTab);
            app.ScriptTable.ColumnName = {'_ Select'; 'ID'; 'Name'; 'Order'; 'Error'; 'Size'; 'Assembled'};
            app.ScriptTable.ColumnEditable = [true false false true false false false];
            app.ScriptTable.CellEditCallback = createCallbackFcn(app, @ScriptTableCellEdit, true);
            app.CheckBox = uicheckbox(app.ImplantTab);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.ScriptFlashTable = uitable(app.ImplantTab);
            app.ScriptFlashTable.ColumnName = {'ID'; 'Size'};
            app.RefreshFlashButton = uibutton(app.ImplantTab, 'push');
            app.RefreshFlashButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshFlashButtonPushed, true);
            app.RefreshFlashButton.Text = 'Refresh';
            app.RefreshButton = uibutton(app.ImplantTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.StopScriptButton = uibutton(app.ImplantTab, 'push');
            app.StopScriptButton.Text = 'Stop Script';
            app.RunScriptOnceButton = uibutton(app.ImplantTab, 'push');
            app.RunScriptOnceButton.Text = 'Run Script Once';
            app.RunScriptTimedButton = uibutton(app.ImplantTab, 'push');
            app.RunScriptTimedButton.Text = 'Run Script Timed';
            app.CheckStatusButton = uibutton(app.ImplantTab, 'push');
            app.CheckStatusButton.Text = 'Check Status';
            app.DeleteScriptButton = uibutton(app.ImplantTab, 'push');
            app.DeleteScriptButton.Text = 'Delete Script';
            app.ScriptExecutionPeriodEditFieldLabel = uilabel(app.ImplantTab);
            app.ScriptExecutionPeriodEditFieldLabel.Text = 'Script Execution Period';
            app.ScriptExecutionPeriodEditField = uieditfield(app.ImplantTab, 'numeric');
            app.ScriptExecutionPeriodEditField.Limits = [80 150];
            app.ScriptExecutionPeriodEditField.Value = 100;
            app.msLabel = uilabel(app.ImplantTab);
            app.msLabel.Text = 'ms';
            app.RefreshButton_2 = uibutton(app.ImplantTab, 'push');
            app.RefreshButton_2.Text = 'Refresh';
            app.PDOTimerTriggeredScriptsButton = uibutton(app.ImplantTab, 'push');
            app.PDOTimerTriggeredScriptsButton.Text = {'PDO/Timer '; 'Triggered'; 'Scripts'};
            app.ScriptLibraryTab = uitab(app.TabGroup);
            app.ScriptLibraryTab.Title = 'Script Library';
            app.DownloadButton = uibutton(app.ScriptLibraryTab, 'push');
            app.DownloadButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadButtonPushed, true);
            app.DownloadButton.Text = 'Download';
            app.AsssembleButton = uibutton(app.ScriptLibraryTab, 'push');
            app.AsssembleButton.ButtonPushedFcn = createCallbackFcn(app, @AsssembleButtonPushed, true);
            app.AsssembleButton.Text = 'Asssemble';
            app.LibraryTable = uitable(app.ScriptLibraryTab);
            app.LibraryTable.ColumnName = {'ID'; '#'; 'Name'; 'File'; 'Size'; 'Assembled'; 'File Modified'};
            app.LibraryTable.RowName = {};
            app.LibraryTable.ColumnSortable = [true true true false true true true];
            app.LibraryTable.ColumnEditable = [true true true false false false false];
            app.LibraryTable.CellSelectionCallback = createCallbackFcn(app, @LibraryTableCellSelection, true);
            app.EditButton = uibutton(app.ScriptLibraryTab, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.AddExistingButton = uibutton(app.ScriptLibraryTab, 'push');
            app.AddExistingButton.ButtonPushedFcn = createCallbackFcn(app, @AddExistingButtonPushed, true);
            app.AddExistingButton.Text = 'Add Existing';
            app.DeleteButton = uibutton(app.ScriptLibraryTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.AddNewButton = uibutton(app.ScriptLibraryTab, 'push');
            app.AddNewButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewButtonPushed, true);
            app.AddNewButton.Text = 'Add New';
            % Show the figure after all components are created
            app.ScriptsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scriptedit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScriptsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScriptsUIFigure)
        end
    end
end