classdef chanProfiler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StimulationProfilerUIFigure  matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        SaveAsMenu                   matlab.ui.container.Menu
        ReadMenu                     matlab.ui.container.Menu
        ExportMenu                   matlab.ui.container.Menu
        XMLMenu                      matlab.ui.container.Menu
        ThingstoNoteButton           matlab.ui.control.StateButton
        ThingstoNotePanel            matlab.ui.container.Panel
        RapidChangePanel_2           matlab.ui.container.Panel
        Label_6                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        PositionDependencePanel      matlab.ui.container.Panel
        Label_4                      matlab.ui.control.Label
        SecondaryActionPanel         matlab.ui.container.Panel
        Label_3                      matlab.ui.control.Label
        PainPanel_2                  matlab.ui.container.Panel
        Label_2                      matlab.ui.control.Label
        PrimaryActionPanel           matlab.ui.container.Panel
        Label                        matlab.ui.control.Label
        respEditField                matlab.ui.control.EditField
        respEditFieldLabel           matlab.ui.control.Label
        KeyEditField                 matlab.ui.control.EditField
        KeyEditFieldLabel            matlab.ui.control.Label
        OverrideNumlockCheckBox      matlab.ui.control.CheckBox
        NoteEditField                matlab.ui.control.EditField
        NoteEditFieldLabel           matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        MuscleEditField              matlab.ui.control.EditField
        MuscleEditFieldLabel         matlab.ui.control.Label
        SaveCurrentPWAinTablePanel   matlab.ui.container.Panel
        ThButton                     matlab.ui.control.Button
        MaxButton                    matlab.ui.control.Button
        ResetTablePanel              matlab.ui.container.Panel
        TableNodesEditField          matlab.ui.control.NumericEditField
        NodesEditFieldLabel          matlab.ui.control.Label
        ResetButton                  matlab.ui.control.Button
        ClearErrorButton             matlab.ui.control.Button
        RegulationOuterPanel         matlab.ui.container.Panel
        RegulationInnerPanel         matlab.ui.container.Panel
        RegEditField                 matlab.ui.control.EditField
        RegButton                    matlab.ui.control.Button
        RegCheckBox                  matlab.ui.control.CheckBox
        ZeroPWButton                 matlab.ui.control.Button
        PWincEditField               matlab.ui.control.NumericEditField
        incrementEditFieldLabel_2    matlab.ui.control.Label
        PAincEditField               matlab.ui.control.NumericEditField
        incrementEditFieldLabel      matlab.ui.control.Label
        PADnButton                   matlab.ui.control.Button
        PAUpButton                   matlab.ui.control.Button
        PWUpButton                   matlab.ui.control.Button
        PWDnButton                   matlab.ui.control.Button
        ErrorEditField               matlab.ui.control.EditField
        ErrorLamp                    matlab.ui.control.Lamp
        ErrorLampLabel               matlab.ui.control.Label
        PAEditField                  matlab.ui.control.NumericEditField
        PWEditField                  matlab.ui.control.NumericEditField
        StimOffButton                matlab.ui.control.Button
        StimOnButton                 matlab.ui.control.Button
        NodeEditField                matlab.ui.control.NumericEditField
        NodeEditFieldLabel           matlab.ui.control.Label
        ChanEditField                matlab.ui.control.NumericEditField
        ChanEditFieldLabel           matlab.ui.control.Label
        NextNodeButton               matlab.ui.control.Button
        PrevNodeButton               matlab.ui.control.Button
        NextChButton                 matlab.ui.control.Button
        PrevChButton                 matlab.ui.control.Button
        PAmALabel                    matlab.ui.control.Label
        PWusSliderLabel              matlab.ui.control.Label
    end


    properties (Access = public)
        nnp=[]; 
        callingFigure=[]; % handle to calling figure
        ltYellow=[1,1,0.8];
        styleYellow=uistyle('BackgroundColor',[1,1,.8]) % light yellow background
        styleSelected=uistyle('BackgroundColor',[1,1,.8],'FontWeight','bold'); % light yellow background
        repeatOn=false; % key repeated before release
        bigStep=10;
        littleStep=1;
        stepSizeMult=3;
        stepSize=1; %step size whien updating
        keyRelUpdate=false; % update on keyRelease if a second keyPresss doesn't occur
        lockoutSec=0.250; % delay to wait between updates when held
        newTime=0; %time since last update
        lockoutCounter=0; % when multiple keypresses are detected without key release, this increments
        lockoutCountLimit=10; % may want to set this to be the same as bigSteps, but for now make separate
        checkLockout=false; % look for lockout - reset on KeyRelease
        lastStimUpdate=0;
        stimUpdateLimit=0.25;  %250ms between updates
        lastUpdate=[];
        totalRows=24; % default to 6 nodes
        saveFile=[];
    end

    properties (Access = private)
        stimOn = false; 
        lastrowselected=0; 
        altPressed = false;
        shiftPressed = false;
        ctrlPressed = false;
        clearPressed = false;
        insertPressed = false;
        
        updatingStim = false;
    end

    methods (Access = private)
    
        function resetStim(app, varargin)
            if nargin == 2
                forceOff = varargin{1};
            else
                forceOff = false;
            end
                
            
            %if the PW and PA are both non zero, turn off Stim, before swithcing channels.  
            % Otherwise, leave everything as is.  If stim is turned off, highlight the non-zero
            % values so operator is reminded to set them appropriately before turning stim back on
            % use Force off between nodes
            if app.stimOn && (forceOff || (app.PWEditField.Value ~= 0 && app.PAEditField.Value ~= 0)) 
                StimOffButtonPushed(app, 0);
                
                %Highlight the non-zero values
                if app.PWEditField.Value ~= 0
                    app.PWEditField.BackgroundColor = [1, 1, 0];
                end
                if app.PAEditField.Value ~= 0
                    app.PAEditField.BackgroundColor = [1, 1, 0];
                end
            end
        end
       
    
        function error = setStim(app)
            app.PWEditField.BackgroundColor = [1, 1, 1];
            app.PAEditField.BackgroundColor = [1, 1, 1];
            
            node = app.NodeEditField.Value;
            ch = app.ChanEditField.Value;
            pw = app.PWEditField.Value;
            pa = round(app.PAEditField.Value*10);
            
            result = app.nnp.write(node, '3212', ch, uint8([pw pa]));
            if isequal(result, 0)
               error = 0;
               app.clearError();
            else
                error = 1;
                app.setError();
                %indicate that values may not have updated
                app.PWEditField.BackgroundColor = [1, 0, 0];
                app.PAEditField.BackgroundColor = [1, 0, 0];
            end
            
            if app.RegCheckBox.Value
                result = app.nnp.write(node, '3210', 5, uint8([3 3 3 3]));
                if isequal(result, 0)
                    app.RegEditField.Value = 'Cleared Status. Press Update';
                else
                    warning('could not set reg status to 3')
                end
            end
        end
        
        function clearError(app)
            app.ErrorLamp.Color = [0.9 0.9 0.9];
            app.ErrorEditField.Value = '';
        end
        
        function setError(app)
            app.ErrorLamp.Color = [1 0 0];
            app.ErrorEditField.Value = app.nnp.lastError;
            %TODO:trigger light turning off after 1 s
        end


        function rowNum = getRowNum(app)
            node=app.NodeEditField.Value;
            chan=app.ChanEditField.Value;
            rowNum=(node-1)*4+chan;
        end
        
        function nextRow(app)
            
            if app.stimOn == false % add a warning here to let us know
                currentRow=getRowNum(app); %current row
                nextRow=currentRow+1;
                if nextRow<=app.totalRows % not at the bottom
                    row=nextRow;
                    data = app.UITable.Data(row,:);
                    app.NodeEditField.Value = data{2};
                    app.ChanEditField.Value = data{3};
                    app.updateRow();
                end
            else
                lastRow=app.getRowNum(); % keep selection where it was
                app.UITable.Selection=[lastRow,1];
            end 

        end
        
        function prevRow(app)
            if app.stimOn == false % add a warning here to let us know
                currentRow=getRowNum(app); %current row
                nextRow=currentRow-1;
                if nextRow>=1 % not less than 1
                    row=nextRow;
                    data = app.UITable.Data(row,:);
                    app.NodeEditField.Value = data{2};
                    app.ChanEditField.Value = data{3};
                    app.updateRow();
                end
            else
                lastRow=app.getRowNum(); % keep selection where it was
                app.UITable.Selection=[lastRow,1];
            end 
        end
    end
    
    methods (Access = public)
        
        function ResetUITable(app)
            totalrows=4*app.TableNodesEditField.Value;
            app.UITable.Selection=[];% deselect before resetting
            removeStyle(app.UITable); % here I am removing before adding later.  I could only add at startup and not on reset
             % The I could make selection style always the Nth style,
             % depending upon number of rows
             app.UITable.Data = cell(totalrows,9); % start with fresh table and create
            data = app.UITable.Data; 
            for i=1:app.TableNodesEditField.Value
                for j=1:4
                    thisrow=(i-1)*4+j;
                    node = i;
                    ch = j;
                    musc=sprintf('N%dC%d',node,ch);
                    thpw = 0;
                    thpa = 100;
                    maxpw = 0;
                    maxpa = 100;                    
                    %data = app.UITable.Data;                 
                    data(thisrow,:) =  [{[], node, ch, musc, thpw, thpa, maxpw, maxpa, ''}];
                    
                end
            end
            app.UITable.Selection=[1,1];
            app.UITable.Data = data;
            app.NodeEditField.Value = 1;
            app.ChanEditField.Value = 1;
            
            app.PAEditField.Value = 10;
            app.PWEditField.Value = 0;
            
            app.MuscleEditField.Value=app.UITable.Data{1,4};% muscle in row 1
            app.lastrowselected=1;
            row=1;
            addStyle(app.UITable,app.styleSelected,'row',row);
        end
        
        function updateRow(app)
            % Set the selection to the correct row based on the current
            % values of node and channel
                row=app.getRowNum();  % need to first set these with cell selection function

            % run the cell selection function
                data = app.UITable.Data(row,:);
                node = data{2};
                ch = data{3};
                pw = 0; % reset to 0
                pa = data{8}; %max amp level
                muscle=data{4};
                note=data{9};
                app.NodeEditField.Value = node;
                app.ChanEditField.Value = ch;
                
                app.PAEditField.Value = pa/10;
                app.PWEditField.Value = pw;
                
                % add Muscle and notes
                app.MuscleEditField.Value=muscle;
                app.NoteEditField.Value=note;
                app.lastrowselected=row;
                removeStyle(app.UITable);
                addStyle(app.UITable,app.styleSelected,'row',row);
        end
        
        function results = overrideNumLock(app,key)
            % Override the numlock by converting the numpad keys to the
            % non-lock values
            switch key
                case 'numpad0'
                    results='insert';
                case 'numpad1'
                    results='end';
                case 'numpad2'
                    results='downarrow';
                case 'numpad3'
                    results='pagedown';
                case 'numpad4'
                    results='leftarrow';
                case 'numpad5'
                    results='clear';
                case 'numpad6'
                    results='rightarrow';
                case 'numpad7'
                    results='home';
                case 'numpad8'
                    results='uparrow';
                case 'numpad9'
                    results='pageup';
                case 'decimal'
                    results='delete';
                otherwise
                    results=key;
            end
            
        end
        
        function processUpDownKey(app,key)  %from testKeyRepeat
%             %if app.clearPressed || app.ctrlPressed
             if app.insertPressed || app.ctrlPressed
                ss=app.stepSize*app.stepSizeMult;
            else
                ss=app.stepSize;
            end
           
            switch key
                case 'uparrow'
                    unit=ss; % increasing
                case 'downarrow'
                    unit=-ss; % decreasing
            end

            %don't allow pattern value changes in Downladed stim mode becuase they will not have an immediate effect
            % need to change all this
%                 i = find(app.Plots{app.activePatternRow}.XData == app.PlotSlider.XData(1), 1);
%                 if ~isempty(i)
%                     if app.altPressed
%                         yc = max(min(app.SmallPlots{app.activePatternRow}.YData(i) + unit, 200),0);
%                         app.SmallPlots{app.activePatternRow}.YData(i) = yc;
%                         %disp('increase PA')
%                         app.PatternValuesTable.Data(i,3) = yc;
%                     else
%                         yc = max(min(app.Plots{app.activePatternRow}.YData(i) + unit, 255),0);
%                         app.Plots{app.activePatternRow}.YData(i) = yc;
%                         %disp('increase PW')
%                         app.PatternValuesTable.Data(i,2) = yc;
%                     end
%                     app.updateFromPatternValuesTable();
%                 end
%                 app.stepSize=app.littleStep; % always set back to little
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            if nargin>2
                app.callingFigure=varargin{2};
            end
            app.totalRows=4*app.TableNodesEditField.Value; % add this here in case I change the default number of nodes
            StimOffButtonPushed(app, 0);
            app.ResetUITable();
%             app.RemoteTunerCheckBox.Visible = false;  %Invisible for now because not implemented yet
%             app.ActionButtonGroup.Visible = false;
            RegCheckBoxValueChanged(app, 0);
            app.lastUpdate=tic;%initialize tic
            app.lastStimUpdate=tic; % initialize tic
        end

        % Button pushed function: PrevChButton
        function PrevChButtonPushed(app, event)
            figure(app.StimulationProfilerUIFigure);
            ch = app.ChanEditField.Value;
            if ch>1
                app.ChanEditField.Value = ch-1;
            end

            app.resetStim();
            app.updateRow();
        end

        % Button pushed function: NextChButton
        function NextChButtonPushed(app, event)
            figure(app.StimulationProfilerUIFigure);
            ch = app.ChanEditField.Value;
            if ch<4
                app.ChanEditField.Value = ch+1;
            end
            app.resetStim();
            app.updateRow();
        end

        % Button pushed function: PrevNodeButton
        function PrevNodeButtonPushed(app, event)
            figure(app.StimulationProfilerUIFigure);
            node = app.NodeEditField.Value;
            if node>1
                app.NodeEditField.Value = node-1;
            end
            app.resetStim(true); %force stim off
            app.updateRow();
        end

        % Button pushed function: NextNodeButton
        function NextNodeButtonPushed(app, event)
           figure(app.StimulationProfilerUIFigure);
           node = app.NodeEditField.Value;
            if node<app.TableNodesEditField.Value;
                app.NodeEditField.Value = node+1;
            end
            app.resetStim(true); %force stim off
            app.updateRow();
        end

        % Button pushed function: StimOnButton
        function StimOnButtonPushed(app, event)
            figure(app.StimulationProfilerUIFigure);
            retry = 0;
            nodeErr=-1;
            while(nodeErr)             
                error = app.setStim();
                if error
                    nodeErr = app.NodeEditField.Value;
                else
                    nodeErr = 0;
                end
                retry = retry+1;
                if retry == 3
                    [modes, ~, ~, net] = app.nnp.getStatus();
                    if net==0
                        resp = questdlg('Network not On.  Turn on network?', 'Profiler');
                        if isequal(resp, 'Yes')
                            if ~app.nnp.networkOn()
                                msgbox('Could not turn on Network', 'Profiler', 'error');
                            end
                        end
                    elseif net==1
                        if nodeErr>0
                            if ~isequal(modes(nodeErr), 'Waiting')
                                resp = questdlg(['Node(s) ' num2str(nodeErr') ' is not online and in waiting mode.  Cycle Network?'], 'Profiler');
                                if isequal(resp, 'Yes')
                                    if ~app.nnp.networkOff()
                                        msgbox('Could not turn off Network', 'Profiler', 'error');
                                    end
                                    if ~app.nnp.networkOn()
                                        msgbox('Could not turn on Network', 'Profiler', 'error');
                                    end
                                end
                            else
                                msgbox(['Node(s) ' num2str(nodeErr') ' is online and in waiting mode, but could not be set'], 'Profiler', 'error');
                            end
                        end
                    else
                         msgbox('Network status could not be determined', 'Profiler', 'error');
                    end
                    return;
                end
            end
            if app.nnp.enterTestStim
                app.stimOn = true;
                app.StimulationProfilerUIFigure.Color = [0.7, 1, 0.7];
                
%                 app.RemoteTunerOuterPanel.BackgroundColor = [0.7,1,0.7];
                app.RegulationOuterPanel.BackgroundColor = [0.7,1,0.7];
                app.StimOnButton.BackgroundColor = [0.7,1,0.7];
                app.StimOffButton.BackgroundColor = [1,0.5,0.5];

            else
                app.setError();
            end
        end

        % Button pushed function: StimOffButton
        function StimOffButtonPushed(app, event)
            figure(app.StimulationProfilerUIFigure);
            if app.nnp.enterWaiting
                app.stimOn = false;
                app.StimulationProfilerUIFigure.Color = [0.94,0.94,0.94];
%                 app.RemoteTunerOuterPanel.BackgroundColor = [0.94,0.94,0.94];
                app.RegulationOuterPanel.BackgroundColor = [0.94,0.94,0.94];
                
                app.StimOffButton.BackgroundColor = [0.94,0.94,0.94];
                app.StimOnButton.BackgroundColor = [0.7,1,0.7];

            else
                app.setError();
            end       
        end

        % Value changed function: PAEditField
        function PAEditFieldValueChanged(app, event)
            value = round(app.PAEditField.Value*10)/10;
            app.PAEditField.Value = value;

            %apply
            app.setStim();
        end

        % Value changed function: PWEditField
        function PWEditFieldValueChanged(app, event)
            %Edit field forces rounded value
            value = app.PWEditField.Value;
            
            %apply
            app.setStim();
        end

        % Value changed function: NodeEditField
        function NodeEditFieldValueChanged(app, event)
            app.resetStim();
        end

        % Value changed function: ChanEditField
        function ChanEditFieldValueChanged(app, event)
            app.resetStim();
        end

        % Value changed function: PAincEditField
        function PAincEditFieldValueChanged(app, event)
            value = round(app.PAincEditField.Value*10)/10;
            app.PAincEditField.Value = value;
            figure(app.StimulationProfilerUIFigure);
        end

        % Button pushed function: PAUpButton
        function PAUpButtonPushed(app, event)
            inc = app.PAincEditField.Value;
            value = app.PAEditField.Value + inc;
            if value > 20
                value = 20;
            end
            app.PAEditField.Value = value;

             %apply
            app.setStim();
        end

        % Button pushed function: PADnButton
        function PADnButtonPushed(app, event)
            inc = app.PAincEditField.Value;
            value = app.PAEditField.Value - inc;
            if value < 0
                value = 0;
            end
            app.PAEditField.Value = value;

            %apply
            app.setStim();
        end

        % Button pushed function: PWDnButton
        function PWDnButtonPushed(app, event)
            figure(app.StimulationProfilerUIFigure);
            inc = app.PWincEditField.Value;
            value = app.PWEditField.Value - inc;
            if value < 0
                value = 0;
            end
            app.PWEditField.Value = value;
            
            %apply
            app.setStim();
        end

        % Button pushed function: PWUpButton
        function PWUpButtonPushed(app, event)
            figure(app.StimulationProfilerUIFigure);
            inc = app.PWincEditField.Value;
            value = app.PWEditField.Value + inc;
            if value > 255
                value = 255;
            end
            app.PWEditField.Value = value;
            
            %apply
            app.setStim();
        end

        % Value changed function: RegCheckBox
        function RegCheckBoxValueChanged(app, event)
            app.RegulationInnerPanel.Visible=app.RegCheckBox.Value;
            figure(app.StimulationProfilerUIFigure);
        end

        % Button pushed function: RegButton
        function RegButtonPushed(app, event)

            if app.RegCheckBox.Value
                node = app.NodeEditField.Value;
                ch = app.ChanEditField.Value;
                
                result = app.nnp.read(node, '3210', 5, 'uint8');
                if length(result) == 4
                    switch result(ch)
                        case 0, app.RegEditField.Value = '0: Out of Regulation!';
                        case 1, app.RegEditField.Value = '1: In Regulation';
                        case 2, app.RegEditField.Value = '2: Pulse<10us';
                        case 3, app.RegEditField.Value = '3: No pulse yet';
                        otherwise, app.RegEditField.Value = [num2str(result(ch)) ': ?'];  
                    end
                else
                    app.RegEditField.Value = 'Could not read';  
                end
                
            end

        end

        % Button pushed function: ZeroPWButton
        function ZeroPWButtonPushed(app, event)
            
            app.PWEditField.Value=0;
             %apply
            app.setStim();
            figure(app.StimulationProfilerUIFigure);
        end

        % Button pushed function: ClearErrorButton
        function ClearErrorButtonPushed(app, event)
            % clear the error message and change LED back to grey
            app.ErrorLamp.Color=[0.90,0.90,0.90];
            app.ErrorEditField.Value='';
        end

        % Close request function: StimulationProfilerUIFigure
        function StimulationProfilerUIFigureCloseRequest(app, event)
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            delete(app)
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
           resp = questdlg('Are you sure you want to reset the profile table?', 'Profiler Table Reset');
            if isequal(resp, 'Yes')
                app.ResetUITable();
                app.UITable.Selection=[1,1];                
                app.lastrowselected=1;
            end
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            if ~isempty(app.safeFile) 
                defname=app.saveFile;
            else
                defname='P0Profile.mat';
            end
            [filename, filepath] = uiputfile('.mat','Save Profiler User Data',defname);
             app.saveFile=[filepath filename];
            if filename
                data = app.UITable.Data;
                save([filepath filename], 'data');
            end
            figure(app.StimulationProfilerUIFigure);
        end

        % Menu selected function: ReadMenu
        function ReadMenuSelected(app, event)
              [filename, filepath] = uigetfile('.mat','Select Profiler History file');
            if filename
                app.saveFile=[filepath filename]; %save for default for later
                ld = load([filepath filename]);
                data = ld.data;
                %Error checking on input file
                if size(data, 2) ~= 9
                    msgbox('Profiler Data file does not have 13 columns of data', 'History File', 'error')
                    return
                end
                if size(data,1) == 0
                    msgbox('Profiler Data file contains no rows', 'History File', 'error')
                    return
                end
                selector = cell2mat(data(:,1));
                if ~isempty(selector)
                    msgbox('Column 1 (Selector) must be all empty', 'History File', 'error')
                    return
                end
%                 active = cell2mat(data(:,2));
%                 if ~islogical(active)
%                     msgbox('Column 2 (Active) must be all logicals', 'History File', 'error')
%                     return
%                 end
                node = cell2mat(data(:,2));
                if any(~ismember(node, 1:127))
                    msgbox('Column 3 (Node) must be 1-127', 'History File', 'error')
                    return
                end
                ch = cell2mat(data(:,3));
                if any(~ismember(ch, 1:4))
                    msgbox('Column 4 (Ch) must be 1-4', 'History File', 'error')
                    return
                end
               
                pw = cell2mat(data(:,5));
                if any(~ismember(pw, 0:255))
                    msgbox('Column 5 (PW) must be 0-255', 'History File', 'error')
                    return
                end
                pa = cell2mat(data(:,6));
                if any(~ismember(pa, 0:200))
                    msgbox('Column 6 (PA) must be 0-200', 'History File', 'error')
                    return
                end
                pw = cell2mat(data(:,7));
                if any(~ismember(pw, 0:255))
                    msgbox('Column 7 (PW) must be 0-255', 'History File', 'error')
                    return
                end
                pa = cell2mat(data(:,8));
                if any(~ismember(pa, 0:200))
                    msgbox('Column 8 (PA) must be 0-200', 'History File', 'error')
                    return
                end
                app.UITable.Data = data;
                app.TableNodesEditField.Value= round(size(data,1)/4);
            end
            app.UITable.Selection=[1,1];                
            app.lastrowselected=1;
            updateRow(app);
            figure(app.StimulationProfilerUIFigure); %return focus after nnp opened if needed
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            row = event.Indices(1);
            col = event.Indices(2);
            
            val = str2double(event.EditData);
            switch col
                case 2 %node
                    if ~ismember(val, 1:127)
                        app.UITable.Data(row, col) = {event.PreviousData};
                    else
                        app.UITable.Data(row, col) = {val};
                    end
                case 3 %ch
                    if ~ismember(val, 1:4)
                        app.UITable.Data(row, col)  = {event.PreviousData};
                    else
                        app.UITable.Data(row, col) = {val};
                    end
                case 5 %pw
                    if ~ismember(val, 0:255)
                        app.UITable.Data(row, col)  = {event.PreviousData};
                    else
                        app.UITable.Data(row, col) = {val};
                    end
                case 6 %pa
                    if ~ismember(val, 0:200)
                        app.UITable.Data(row, col)  = {event.PreviousData};
                    else
                        app.UITable.Data(row, col) = {val};
                    end
                case 7 %pw
                    if ~ismember(val, 0:255)
                        app.UITable.Data(row, col)  = {event.PreviousData};
                    else
                        app.UITable.Data(row, col) = {val};
                    end
                case 8 %pa
                    if ~ismember(val, 0:200)
                        app.UITable.Data(row, col)  = {event.PreviousData};
                    else
                        app.UITable.Data(row, col) = {val};
                    end
%                 case 10 %pw
%                     if ~ismember(val, 0:255)
%                         app.UITable.Data(row, col)  = {event.PreviousData};
%                     else
%                         app.UITable.Data(row, col) = {val};
%                     end
%                 case 11 %pa
%                     if ~ismember(val, 0:200)
%                         app.UITable.Data(row, col)  = {event.PreviousData};
%                     else
%                         app.UITable.Data(row, col) = {val};
%                     end
            end      
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            row = event.Indices(1);
            col = event.Indices(2);             
%             app.lastrowselected=row; % need to revert back to this if stim On

            % the code below needs to be put into a separate function so
            % that I can call it when changing row programmatically
            % Below I only change these numbers if stim is off, but that
            % would throw off the row pointer.  
            % What I need to do is first stop the stim (as if hitting
            % stimOff, then run the code below.
            % Will need to decide what to do if an error occurs
            % Alternatively, I could not allow selection of row if stim is
            % ON.  That would mean returning to prior selection and giving
            % a warning.
            if col == 1 && app.stimOn == false
                data = app.UITable.Data(row,:);
                app.NodeEditField.Value = data{2};
                app.ChanEditField.Value = data{3};
               app.updateRow();
            else
                lastRow=app.getRowNum(); % keep selection where it was
                app.UITable.Selection=[lastRow,col];
            end 
            figure(app.StimulationProfilerUIFigure);
        end

        % Button pushed function: ThButton
        function ThButtonPushed(app, event)
          %fill in reference cells in row
            row=app.getRowNum(); % should also be able to use lastrowselected
            pw=app.PWEditField.Value;
            amp=app.PAEditField.Value;
            data = app.UITable.Data(row,:);
            data{5}=pw;
            data{6}=amp*10;
            app.UITable.Data(row,:)=data;   
            figure(app.StimulationProfilerUIFigure);
        end

        % Button pushed function: MaxButton
        function MaxButtonPushed(app, event)
             %fill in reference cells in row
            row=app.getRowNum(); % should also be able to use lastrowselected
            pw=app.PWEditField.Value;
            amp=app.PAEditField.Value;
            data = app.UITable.Data(row,:);
            data{7}=pw;
            data{8}=amp*10;
            app.UITable.Data(row,:)=data;
            figure(app.StimulationProfilerUIFigure);
        end

        % Value changed function: MuscleEditField
        function MuscleEditFieldValueChanged(app, event)
            value = app.MuscleEditField.Value;
            row=app.getRowNum(); % should also be able to use lastrowselected
            %change table to match value
            data = app.UITable.Data(row,:);            
            data{4}=value;
            app.UITable.Data(row,:)=data;
            figure(app.StimulationProfilerUIFigure);
        end

        % Value changed function: NoteEditField
        function NoteEditFieldValueChanged(app, event)
            value = app.NoteEditField.Value;
            row=app.getRowNum(); % should also be able to use lastrowselected
            %change table to match value
            data = app.UITable.Data(row,:);            
            data{9}=value;
            app.UITable.Data(row,:)=data;
            figure(app.StimulationProfilerUIFigure);
        end

        % Key press function: StimulationProfilerUIFigure
        function StimulationProfilerUIFigureKeyPress(app, event)
            key = event.Key;
            if app.OverrideNumlockCheckBox.Value
                key= app.overrideNumLock(key);
            end
            switch key
                case 'alt'
                    app.altPressed = true;
                case 'shift'
                    app.shiftPressed = true;
                case 'control'
                    app.ctrlPressed = true;
                
                case 'insert'
                    app.insertPressed = true;
                case {'semicolon'} % decrease amp
                    
            
                case 'quote' % increase amp
   

                case {'period','pageup'} % may need to see how the rows advance (up or down)
                    if app.altPressed % increase amp
                        PAUpButtonPushed(app, 0);
                    else
                        NextNodeButtonPushed(app, 0);
                    end

            
                case {'comma','pagedown'}
                    if app.altPressed % decrease amp
                         PADnButtonPushed(app, 0);
                    else
                        PrevNodeButtonPushed(app, 0);
                    end            


                case 'uparrow'
                        PWUpButtonPushed(app,0);
                case 'downarrow'
                        PWDnButtonPushed(app,0);
%                 case {'uparrow', 'downarrow'} %up or down arrow
%                     app.lockoutSec=0.10; % added here to change without restarting
%                     
%                     if app.checkLockout   % after key release, do not need to check lockout
%                         app.newTime=toc(app.lastUpdate);
%                         if app.newTime>app.lockoutSec % ignore if not long enough
%                             
%                             app.lastUpdate=tic;
%                             app.stepSize=app.bigStep; % held so us big step
%                             app.processUpDownKey(key);
%                             app.checkLockout=true;
%                             
%                             app.keyRelUpdate=false;
%                         end
%                     %                     end
%                     else % not checkLockout
%                     
%                         app.checkLockout=true;
%                         
%                         %
%                         if app.keyRelUpdate % should always be false at this point
%                            
%                             app.keyRelUpdate=false; % more than one KP
%                             % Since this is first duplicate, send out change
%                             app.lastUpdate=tic;
%                             app.stepSize=app.bigStep; % held so us big step
%                             app.processUpDownKey(key);
%                         
%                         else % not keyRelease and not checkLockout
%                             app.keyRelUpdate=true; % first time through
%                         end
%                     end
            end
        end

        % Key release function: StimulationProfilerUIFigure
        function StimulationProfilerUIFigureKeyRelease(app, event)
            key = event.Key;
            if app.OverrideNumlockCheckBox.Value
                key= app.overrideNumLock(key);
            end
            app.KeyEditField.Value=key;
            app.respEditField.Value='';

            switch key
                case 'leftarrow' % channel down
                    app.respEditField.Value='left';         
                    % PrevChButtonPushed(app,0);
                    app.prevRow();
                case 'rightarrow' % channel up
                    app.respEditField.Value='right';
                    app.nextRow();
                    % NextChButtonPushed(app,0);

                case 'alt'
                    app.altPressed = false;
                     app.respEditField.Value='alt';
            
                case 'shift'
                    app.shiftPressed = false;
                     app.respEditField.Value='shift';
            
                case 'control'
                    app.ctrlPressed = false;
                    app.respEditField.Value='ctl';
                case {'backslash','multiply'} % copy PW/Amp to REF in table
                    % app.clearPressed = false;
%                     RefButtonPushed(app, 0);
                case {'home','semicolon'}
                    % Max PW/Amp in profile
                        MaxButtonPushed(app,0);
                case {'end','quote'}
                    % Threshold PW/Amp in profile
                        ThButtonPushed(app,0);
                case 'rightbracket' % Current row not active
                    % Unmute all
                    app.respEditField.Value='rb';

                case 'leftbracket' % current row active
                    % Mute all
                    app.respEditField.Value='lbt';
                case 'insert'
                    app.insertPressed = false;
                    app.respEditField.Value='ins';
                case 'divide'
                    if app.insertPressed % for normal keypad, numpad not locked
                        % current active
                        app.respEditField.Value='divide';
                    else
                        % current not active
            
                    end
              

                    % update row
            
                case {'uparrow', 'downarrow'} %up or down arrow
            
                    if app.keyRelUpdate
                        app.lastUpdate=tic;
                        app.stepSize=app.littleStep;
                        app.processUpDownKey(key);
                    end
            
                case {'hyphen','delete'}
                    StimOffButtonPushed(app, 0);
            
                case {'subtract','equal'} % stim toggle
                    if app.stimOn
                        StimOffButtonPushed(app, 0);
                    else
                        StimOnButtonPushed(app, 0);
                    end
            
            
                case 'space'
                    app.respEditField.Value='space';
                    if app.ctrlPressed && app.stimOn == false
                        StimOnButtonPushed(app, 0);
                    else
                        StimOffButtonPushed(app, 0);
                    end
            
            end
            %for key repeat logic
            app.checkLockout=false;
            app.keyRelUpdate=false;

        end

        % Value changed function: TableNodesEditField
        function TableNodesEditFieldValueChanged(app, event)
            value = app.TableNodesEditField.Value;
            newRows=value*4;
            if newRows<app.totalRows
                refillrows=newRows;
                % give warning that you are going to lose data
                % ask for response
                % if no, then return
                % otherwise copy as much as you can
            else
                refillrows=app.totalRows; % still the old value
            end
            data=app.UITable.Data; %make a copy of prior data
            app.ResetUITable();
            newData=app.UITable.Data;
            for i=1:refillrows
                newData(i,:)=data(i,:);
            end
            app.UITable.Data=newData;    
            app.totalRows=4*value; % add this here in case I change the default number of nodes
        end

        % Value changed function: ThingstoNoteButton
        function ThingstoNoteButtonValueChanged(app, event)
            value = app.ThingstoNoteButton.Value;
            if value
                app.ThingstoNotePanel.Position=[1036,23,374,579];
            else
                app.ThingstoNotePanel.Position=[1036,-600,374,579];
            end
            figure(app.StimulationProfilerUIFigure);
        end

        % Menu selected function: XMLMenu
        function XMLMenuSelected(app, event)
            % save the user profile data as XML file
            % get a file name and save the data to that file
            % include a default file name.  
            % start with the current filename, with xml extension for
            % default
            [~,a,~]=fileparts(app.saveFile); % ADD THUIS LATER
            %a=[]; %placeholder for save file
            if isempty(a)
                defaultName='PxProfileData.xml';
            else
                defaultName = [a,'.xml'];
            end
            [filename, pathname ] = uiputfile('*.xml', 'Save Profile Data as XML',defaultName);
            if filename == 0
                figure(app.StimulationProfilerUIFigure); %return focus after nnp opened if needed
                return
            else
                xmlSaveFile = fullfile(pathname, filename); % do not save this file name
                data=app.UITable.Data(:,2:end); % omit the select column because cannot write empty cells to XML
                variableNames={'Node'; 'Ch'; 'Muscle'; 'ThPW'; 'ThA'; 'MaxPW'; 'MaxA'; 'Notes'};
                profileTable=cell2table(data,'VariableNames',variableNames); % converts cell array to table
                writetable(profileTable,xmlSaveFile,'FileType','xml','TableNodeName','profileTable');
                %                 app.TopMessage('Profile Data saved to file');
            end
            figure(app.StimulationProfilerUIFigure); %return focus after nnp opened if needed
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StimulationProfilerUIFigure = uifigure('Visible', 'off');
            app.StimulationProfilerUIFigure.Name = 'Stimulation Profiler';
            app.StimulationProfilerUIFigure.CloseRequestFcn = createCallbackFcn(app, @StimulationProfilerUIFigureCloseRequest, true);
            app.StimulationProfilerUIFigure.KeyPressFcn = createCallbackFcn(app, @StimulationProfilerUIFigureKeyPress, true);
            app.StimulationProfilerUIFigure.KeyReleaseFcn = createCallbackFcn(app, @StimulationProfilerUIFigureKeyRelease, true);
            app.FileMenu = uimenu(app.StimulationProfilerUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'SaveAs';
            app.ReadMenu = uimenu(app.FileMenu);
            app.ReadMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadMenuSelected, true);
            app.ReadMenu.Text = 'Read';
            app.ExportMenu = uimenu(app.StimulationProfilerUIFigure);
            app.ExportMenu.Text = 'Export';
            app.XMLMenu = uimenu(app.ExportMenu);
            app.XMLMenu.MenuSelectedFcn = createCallbackFcn(app, @XMLMenuSelected, true);
            app.XMLMenu.Text = 'XML';
            app.PWusSliderLabel = uilabel(app.StimulationProfilerUIFigure);
            app.PWusSliderLabel.Text = 'PW (us)';
            app.PAmALabel = uilabel(app.StimulationProfilerUIFigure);
            app.PAmALabel.Text = 'PA (mA)';
            app.PrevChButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.PrevChButton.ButtonPushedFcn = createCallbackFcn(app, @PrevChButtonPushed, true);
            app.PrevChButton.Text = '◄';
            app.NextChButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.NextChButton.ButtonPushedFcn = createCallbackFcn(app, @NextChButtonPushed, true);
            app.NextChButton.Text = '►';
            app.PrevNodeButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.PrevNodeButton.ButtonPushedFcn = createCallbackFcn(app, @PrevNodeButtonPushed, true);
            app.PrevNodeButton.Text = '◄';
            app.NextNodeButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.NextNodeButton.ButtonPushedFcn = createCallbackFcn(app, @NextNodeButtonPushed, true);
            app.NextNodeButton.Text = '►';
            app.ChanEditFieldLabel = uilabel(app.StimulationProfilerUIFigure);
            app.ChanEditFieldLabel.Text = 'Chan';
            app.ChanEditField = uieditfield(app.StimulationProfilerUIFigure, 'numeric');
            app.ChanEditField.Limits = [1 4];
            app.ChanEditField.RoundFractionalValues = 'on';
            app.ChanEditField.ValueDisplayFormat = '%.0f';
            app.ChanEditField.ValueChangedFcn = createCallbackFcn(app, @ChanEditFieldValueChanged, true);
            app.ChanEditField.Value = 1;
            app.NodeEditFieldLabel = uilabel(app.StimulationProfilerUIFigure);
            app.NodeEditFieldLabel.Text = 'Node';
            app.NodeEditField = uieditfield(app.StimulationProfilerUIFigure, 'numeric');
            app.NodeEditField.Limits = [1 127];
            app.NodeEditField.RoundFractionalValues = 'on';
            app.NodeEditField.ValueDisplayFormat = '%.0f';
            app.NodeEditField.ValueChangedFcn = createCallbackFcn(app, @NodeEditFieldValueChanged, true);
            app.NodeEditField.Value = 1;
            app.StimOnButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.StimOnButton.ButtonPushedFcn = createCallbackFcn(app, @StimOnButtonPushed, true);
            app.StimOnButton.Text = 'Stim On';
            app.StimOffButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.StimOffButton.ButtonPushedFcn = createCallbackFcn(app, @StimOffButtonPushed, true);
            app.StimOffButton.Text = 'Stim Off';
            app.PWEditField = uieditfield(app.StimulationProfilerUIFigure, 'numeric');
            app.PWEditField.Limits = [0 255];
            app.PWEditField.RoundFractionalValues = 'on';
            app.PWEditField.ValueDisplayFormat = '%.0f';
            app.PWEditField.ValueChangedFcn = createCallbackFcn(app, @PWEditFieldValueChanged, true);
            app.PAEditField = uieditfield(app.StimulationProfilerUIFigure, 'numeric');
            app.PAEditField.Limits = [0 20];
            app.PAEditField.ValueDisplayFormat = '%.1f';
            app.PAEditField.ValueChangedFcn = createCallbackFcn(app, @PAEditFieldValueChanged, true);
            app.PAEditField.Value = 10;
            app.ErrorLampLabel = uilabel(app.StimulationProfilerUIFigure);
            app.ErrorLampLabel.Text = 'Error';
            app.ErrorLamp = uilamp(app.StimulationProfilerUIFigure);
            app.ErrorEditField = uieditfield(app.StimulationProfilerUIFigure, 'text');
            app.ErrorEditField.Editable = 'off';
            app.PWDnButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.PWDnButton.ButtonPushedFcn = createCallbackFcn(app, @PWDnButtonPushed, true);
            app.PWDnButton.Text = '▼';
            app.PWUpButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.PWUpButton.ButtonPushedFcn = createCallbackFcn(app, @PWUpButtonPushed, true);
            app.PWUpButton.Text = '▲';
            app.PAUpButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.PAUpButton.ButtonPushedFcn = createCallbackFcn(app, @PAUpButtonPushed, true);
            app.PAUpButton.Text = '▲';
            app.PADnButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.PADnButton.ButtonPushedFcn = createCallbackFcn(app, @PADnButtonPushed, true);
            app.PADnButton.Text = '▼';
            app.incrementEditFieldLabel = uilabel(app.StimulationProfilerUIFigure);
            app.incrementEditFieldLabel.Text = 'increment';
            app.PAincEditField = uieditfield(app.StimulationProfilerUIFigure, 'numeric');
            app.PAincEditField.Limits = [0 5];
            app.PAincEditField.ValueDisplayFormat = '%.1f';
            app.PAincEditField.ValueChangedFcn = createCallbackFcn(app, @PAincEditFieldValueChanged, true);
            app.PAincEditField.Value = 1;
            app.incrementEditFieldLabel_2 = uilabel(app.StimulationProfilerUIFigure);
            app.incrementEditFieldLabel_2.Text = 'increment';
            app.PWincEditField = uieditfield(app.StimulationProfilerUIFigure, 'numeric');
            app.PWincEditField.Limits = [0 20];
            app.PWincEditField.RoundFractionalValues = 'on';
            app.PWincEditField.ValueDisplayFormat = '%.0f';
            app.PWincEditField.Value = 5;
            app.ZeroPWButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.ZeroPWButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroPWButtonPushed, true);
            app.ZeroPWButton.Text = 'Reset PW';
            app.RegulationOuterPanel = uipanel(app.StimulationProfilerUIFigure);
            app.RegCheckBox = uicheckbox(app.RegulationOuterPanel);
            app.RegCheckBox.ValueChangedFcn = createCallbackFcn(app, @RegCheckBoxValueChanged, true);
            app.RegCheckBox.Tooltip = {'Check compliance of stim channels'};
            app.RegCheckBox.Text = 'Regulation ';
            app.RegulationInnerPanel = uipanel(app.RegulationOuterPanel);
            app.RegulationInnerPanel.Visible = 'off';
            app.RegButton = uibutton(app.RegulationInnerPanel, 'push');
            app.RegButton.ButtonPushedFcn = createCallbackFcn(app, @RegButtonPushed, true);
            app.RegButton.Tooltip = {'Reg of current channel (all active channels if history table Reg checkbox selected'};
            app.RegButton.Text = {'Update'; 'Status'};
            app.RegEditField = uieditfield(app.RegulationInnerPanel, 'text');
            app.ClearErrorButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.ClearErrorButton.ButtonPushedFcn = createCallbackFcn(app, @ClearErrorButtonPushed, true);
            app.ClearErrorButton.Text = 'CLR';
            app.ResetTablePanel = uipanel(app.StimulationProfilerUIFigure);
            app.ResetTablePanel.Title = 'Reset Table';
            app.ResetButton = uibutton(app.ResetTablePanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.NodesEditFieldLabel = uilabel(app.ResetTablePanel);
            app.NodesEditFieldLabel.Text = 'Nodes';
            app.TableNodesEditField = uieditfield(app.ResetTablePanel, 'numeric');
            app.TableNodesEditField.Limits = [1 6];
            app.TableNodesEditField.ValueChangedFcn = createCallbackFcn(app, @TableNodesEditFieldValueChanged, true);
            app.TableNodesEditField.Value = 6;
            app.SaveCurrentPWAinTablePanel = uipanel(app.StimulationProfilerUIFigure);
            app.SaveCurrentPWAinTablePanel.Title = 'Save Current PW/A in Table';
            app.MaxButton = uibutton(app.SaveCurrentPWAinTablePanel, 'push');
            app.MaxButton.ButtonPushedFcn = createCallbackFcn(app, @MaxButtonPushed, true);
            app.MaxButton.Text = 'Max';
            app.ThButton = uibutton(app.SaveCurrentPWAinTablePanel, 'push');
            app.ThButton.ButtonPushedFcn = createCallbackFcn(app, @ThButtonPushed, true);
            app.ThButton.Text = 'Th';
            app.MuscleEditFieldLabel = uilabel(app.StimulationProfilerUIFigure);
            app.MuscleEditFieldLabel.Text = 'Muscle';
            app.MuscleEditField = uieditfield(app.StimulationProfilerUIFigure, 'text');
            app.MuscleEditField.ValueChangedFcn = createCallbackFcn(app, @MuscleEditFieldValueChanged, true);
            app.MuscleEditField.Tooltip = {'Edit to change name in table'};
            app.MuscleEditField.Value = 'Muscle';
            app.UITable = uitable(app.StimulationProfilerUIFigure);
            app.UITable.ColumnName = {'Select'; 'Node'; 'Ch'; 'Muscle'; 'ThPW'; 'ThA'; 'MaxPW'; 'MaxA'; 'Notes'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false false false false false false false false];
            app.UITable.ColumnEditable = [false true true true true true true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Multiselect = 'off';
            app.NoteEditFieldLabel = uilabel(app.StimulationProfilerUIFigure);
            app.NoteEditFieldLabel.Text = 'Note';
            app.NoteEditField = uieditfield(app.StimulationProfilerUIFigure, 'text');
            app.NoteEditField.ValueChangedFcn = createCallbackFcn(app, @NoteEditFieldValueChanged, true);
            app.OverrideNumlockCheckBox = uicheckbox(app.StimulationProfilerUIFigure);
            app.OverrideNumlockCheckBox.Text = 'Override Numlock';
            app.OverrideNumlockCheckBox.Value = true;
            app.KeyEditFieldLabel = uilabel(app.StimulationProfilerUIFigure);
            app.KeyEditFieldLabel.Text = 'Key';
            app.KeyEditField = uieditfield(app.StimulationProfilerUIFigure, 'text');
            app.respEditFieldLabel = uilabel(app.StimulationProfilerUIFigure);
            app.respEditFieldLabel.Text = 'resp';
            app.respEditField = uieditfield(app.StimulationProfilerUIFigure, 'text');
            app.ThingstoNotePanel = uipanel(app.StimulationProfilerUIFigure);
            app.ThingstoNotePanel.Title = 'Things to Note';
            app.PrimaryActionPanel = uipanel(app.ThingstoNotePanel);
            app.PrimaryActionPanel.Title = 'Primary Action';
            app.Label = uilabel(app.PrimaryActionPanel);
            app.Label.Text = {'What does it mainly do?  Even if it'; 'spills to another action or was not '; 'intended for this action.'};
            app.PainPanel_2 = uipanel(app.ThingstoNotePanel);
            app.PainPanel_2.Title = 'Pain';
            app.Label_2 = uilabel(app.PainPanel_2);
            app.Label_2.Text = {'Anytihing uncomfortable. Note whether '; 'it is due to position, stimulus level '; 'or the rate the stimulation is applied'};
            app.SecondaryActionPanel = uipanel(app.ThingstoNotePanel);
            app.SecondaryActionPanel.Title = 'Secondary Action';
            app.Label_3 = uilabel(app.SecondaryActionPanel);
            app.Label_3.Text = {'If it spills to another muscle, it is helpful '; 'to know where, how strongly, '; 'and whether it is a helpful addition for '; 'a particular pattern.'};
            app.PositionDependencePanel = uipanel(app.ThingstoNotePanel);
            app.PositionDependencePanel.Title = 'Position Dependence';
            app.Label_4 = uilabel(app.PositionDependencePanel);
            app.Label_4.Text = {'Note changes due to arm, hand or wrist '; 'position.  Note if consistent or irregular. '; 'Now how it changes with stim level.'};
            app.Label_5 = uilabel(app.ThingstoNotePanel);
            app.Label_5.Text = {'Note anything unusual or significant to monitor or help '; 'in pattern editing'};
            app.RapidChangePanel_2 = uipanel(app.ThingstoNotePanel);
            app.RapidChangePanel_2.Title = 'Rapid Change';
            app.Label_6 = uilabel(app.RapidChangePanel_2);
            app.Label_6.Text = {'Also called ''high gain'', meaning there is an'; 'area that changes rapidly with PW. Note where,'; ' and adjust levels in the pattern editor'};
            app.ThingstoNoteButton = uibutton(app.StimulationProfilerUIFigure, 'state');
            app.ThingstoNoteButton.ValueChangedFcn = createCallbackFcn(app, @ThingstoNoteButtonValueChanged, true);
            app.ThingstoNoteButton.Text = 'Things to Note';
            % Show the figure after all components are created
            app.StimulationProfilerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = chanProfiler(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StimulationProfilerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StimulationProfilerUIFigure)
        end
    end
end