classdef channeledit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChannelsEditorFigure  matlab.ui.Figure
        UITable               matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            channels = parentApp.nnps.channels;
            k=1;
            for i=1:size(channels,1)
                for j=1:size(channels,2)
                    d{k,1} = i; %node
                    d{k,2} = j; %ch
                    d{k,3} = channels{i}.name;
                    d{k,4} = channels{i}.min;
                    d{k,5} = channels{i}.max;
                    
                    k=k+1;
                end
            end
            app.UITable.Data = d;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChannelsEditorFigure = uifigure('Visible', 'off');
            app.ChannelsEditorFigure.Name = 'Channels Editor';
            app.UITable = uitable(app.ChannelsEditorFigure);
            app.UITable.ColumnName = {'Node'; 'Channel'; 'Muscle Name'; 'Min'; 'Max'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.ChannelsEditorFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = channeledit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChannelsEditorFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChannelsEditorFigure)
        end
    end
end