classdef groupedit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PatternGroupEditorFigure    matlab.ui.Figure
        UITable                     matlab.ui.control.Table
        AddChannelPatternButton     matlab.ui.control.Button
        RemoveChannelPatternButton  matlab.ui.control.Button
        ApplyButton                 matlab.ui.control.Button
        RevertButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        groups = []; % Description
        row = [];
        parentApp = [];
    end
    
    methods (Access = private)
        
        function populateTable(app)
            for i=1:length(app.groups)
                d{i,1} = i;
                if ~isempty(app.groups)
                    d{i,2} = app.groups{i}.cmdID;
                    d{i,3} = app.groups{i}.name;
                    d{i,4} = app.groups{i}.download;
                    d{i,5} = app.groups{i}.notes;
                end
            end
            app.UITable.Data = d;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PARENTAPP)
            app.parentApp = PARENTAPP;
            app.groups = app.parentApp.nnps.groups;
            app.populateTable();
            
            app.UITable.ColumnFormat = {'char', 'char'};
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.row = event.Indices(1);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            row = event.Indices(1);    
            col = event.Indices(2);
            
            if col == 2
                newData = event.NewData;
                if ischar(newData)
                    newData = str2double(newData);
                end
                if isnan(newData) || newData>8 || newData<1
                   app.UITable.Data{row, col} = event.PreviousData;
                end
            end
        end

        % Button pushed function: AddChannelPatternButton
        function AddChannelPatternButtonPushed(app, event)


            n = length(app.groups);
            app.groups{n+1} = struct('name', '?', 'cmdID', '?','download', false, 'notes', '');

            app.populateTable();
        end

        % Button pushed function: RemoveChannelPatternButton
        function RemoveChannelPatternButtonPushed(app, event)
            if isempty(app.row)  || app.row > size(app.UITable.Data, 1)
                return;
            end
            
            d = app.UITable.Data;

            app.groups(app.row) = [];

            d(app.row,:) = [];
            app.UITable.Data = d;
        end

        % Close request function: PatternGroupEditorFigure
        function PatternGroupEditorFigureCloseRequest(app, event)
            if ~isequal(app.parentApp.nnps.groups, app.groups)
                resp = questdlg('Apply Changes');
                if isequal(resp, 'Yes')
                    app.parentApp.nnps.groupe = app.groups;
                elseif ~isequal(resp, 'No')
                    return
                end
            end
            delete(app)    
                  
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
             app.parentApp.nnps.groups = app.groups;
        end

        % Button pushed function: RevertButton
        function RevertButtonPushed(app, event)
            app.groups = app.parentApp.nnps.groups;
            app.populateTable();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PatternGroupEditorFigure = uifigure('Visible', 'off');
            app.PatternGroupEditorFigure.Name = 'Pattern Group Editor';
            app.PatternGroupEditorFigure.CloseRequestFcn = createCallbackFcn(app, @PatternGroupEditorFigureCloseRequest, true);
            app.UITable = uitable(app.PatternGroupEditorFigure);
            app.UITable.ColumnName = {'Group'; 'Cmd ID'; 'Name'; 'Download'; 'Notes'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.AddChannelPatternButton = uibutton(app.PatternGroupEditorFigure, 'push');
            app.AddChannelPatternButton.ButtonPushedFcn = createCallbackFcn(app, @AddChannelPatternButtonPushed, true);
            app.AddChannelPatternButton.Text = '+';
            app.RemoveChannelPatternButton = uibutton(app.PatternGroupEditorFigure, 'push');
            app.RemoveChannelPatternButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveChannelPatternButtonPushed, true);
            app.RemoveChannelPatternButton.Text = '-';
            app.ApplyButton = uibutton(app.PatternGroupEditorFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.RevertButton = uibutton(app.PatternGroupEditorFigure, 'push');
            app.RevertButton.ButtonPushedFcn = createCallbackFcn(app, @RevertButtonPushed, true);
            app.RevertButton.Text = 'Revert';
            % Show the figure after all components are created
            app.PatternGroupEditorFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = groupedit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PatternGroupEditorFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PatternGroupEditorFigure)
        end
    end
end