classdef patternedit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FIleMenu                      matlab.ui.container.Menu
        OpenMenu                      matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        SaveAsMenu                    matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        UIAxes                        matlab.ui.control.UIAxes
        EditModeSwitchLabel           matlab.ui.control.Label
        EditModeSwitch                matlab.ui.control.Switch
        ShowToolTipsCheckBox          matlab.ui.control.CheckBox
        ViewModeSwitch                matlab.ui.control.Switch
        StimulationPanel              matlab.ui.container.Panel
        StimONButton                  matlab.ui.control.Button
        StimOFFButton                 matlab.ui.control.Button
        StimModeSwitchLabel           matlab.ui.control.Label
        StimModeSwitch                matlab.ui.control.Switch
        HighBandwidthUsePDOsCheckBox  matlab.ui.control.CheckBox
        CommandValueEditFieldLabel    matlab.ui.control.Label
        CommandValueEditField         matlab.ui.control.NumericEditField
        PatternDropDownLabel          matlab.ui.control.Label
        PatternDropDown               matlab.ui.control.DropDown
        ModuleDropDownLabel           matlab.ui.control.Label
        ModuleDropDown                matlab.ui.control.DropDown
        SystemPatternsPanel           matlab.ui.container.Panel
        WritetoModulesButton          matlab.ui.control.Button
        ReadfromModulesButton         matlab.ui.control.Button
        CompareModulesButton          matlab.ui.control.Button
        CurrentModuleOnlyCheckBox     matlab.ui.control.CheckBox
        Panel                         matlab.ui.container.Panel
        PatternTable                  matlab.ui.control.Table
        ColorTable                    matlab.ui.control.Table
        PatternTable_2                matlab.ui.control.Table
        ButtonUp                      matlab.ui.control.Button
        ButtonDown                    matlab.ui.control.Button
        ButtonTop                     matlab.ui.control.Button
        ButtonBottom                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        editMode = 'x'; % Description
        Plots = [];
        PlotBars = [];
        PlotSlider = [];
    end


    methods (Access = public)
        
        function SliderClickCallback(app, src, evt )
            disp('Clicked Slider')
            hLine = src;
            app.UIFigure.WindowButtonMotionFcn = {@app.MovePointCallback, hLine, 'Slider'}; 
            app.UIFigure.WindowButtonUpFcn = {@app.ReleaseClickCallback};
        end
        
        function LineClickCallback(app, src, evt )
            %LINECLICK Called on a click of a pattern or breakpoint line
            %
            % If 'HitTest' is set to 'on' clicking the line will trigger this callback
            % (not sure if threshold for click can be adjusted.  It doesn't seem
            % affected by line thickness)
            %  HitTest      pattern lines    breakpoint lines
            %  y-edit mode  ON               OFF
            %  x-edit mode  OFF              ON
            %
            % src is the handle of the clicked line
            %       (pattern in y-edit mode OR breakpoint in x-edit mode)
            % evt is used to find the clicked point
            %
            %  The following inputs are only required for x-edit mode since all lines
            %  need to be updated when changing x-value, but are also passed during
            %  y-edit mode for simplicity:
            % hPlots are handles to all the Patterns plotted
            % hPlotBars are handles to all the Breakpoint lines
            %
            % Note:
            % axis CurrentPoint reports non NaN values when outside of plot area
            % figure CurrentPoint reports values in figure pixels, not axis units
            % evt.IntersectionPoint reports NaN values when outside plot area
            
            
            breakPointThreshold = 4; %if this is too low, we may create a new
            %breakpoint at the same time that we remove one
            
            xc = evt.IntersectionPoint(1)
            yc = evt.IntersectionPoint(2)
            hLine = src;
            
            % Callback entered on line click. Find closest point on the line
            switch app.editMode
                case 'y'
                    y=abs(hLine.YData-yc);
                    x=abs(hLine.XData-xc);
                    d=y.^2+x.^2;
                    index=find(d==min(d));
                case 'x'
                    switch evt.Button
                        case 1 %left click
                            index = find(cellfun(@(x) x==hLine, app.PlotBars))+1; 
                        case 3 %right click
                            
                            index = find(cellfun(@(x) x==hLine, app.PlotBars))+1; %find breakpoint that matches current line handle
                            if isempty(index) %line handle must be a pattern line
                                xc = round(xc);
                                if any(abs(app.Plots{1}.XData-xc)<breakPointThreshold)
                                    disp('Breakpoint already exists at this value')
                                    return;
                                end
                                %insert the new breakpoint
                                before = app.Plots{1}.XData<xc;
                                after  = app.Plots{1}.XData>xc;
                                xbefore = app.Plots{1}.XData(before);
                                xafter = app.Plots{1}.XData(after);
                                for i=1:length(app.Plots)
                                    ybefore = app.Plots{i}.YData(before);
                                    yafter = app.Plots{i}.YData(after);
                                    %interpolate new y value
                                    yc = round(ybefore(end) + (xc-xbefore(end))*(yafter(1)-ybefore(end))/(xafter(1)-xbefore(end)));
                                    app.Plots{i}.XData = [xbefore, xc, xafter];
                                    app.Plots{i}.YData = [ybefore, yc, yafter];
                                end
                                index = length(xbefore);
                                for i=length(app.PlotBars):-1:index+1
                                    app.PlotBars{i}.XData = app.PlotBars{i-1}.XData;
                                end
                                app.PlotBars{index}.XData = [xc xc];
                            else
                                resp = questdlg('Delete Breakpoint?');
                                if strcmp(resp, 'Yes')
                                    for i=1:length(app.Plots)
                                        app.Plots{i}.XData = [app.Plots{i}.XData(1:index-1) app.Plots{i}.XData(index+1:end)];
                                        app.Plots{i}.YData = [app.Plots{i}.YData(1:index-1) app.Plots{i}.YData(index+1:end)];
                                    end
                                    for i=index-1:length(app.PlotBars)-2
                                        app.PlotBars{i}.XData = app.PlotBars{i+1}.XData;
                                    end
                                end
                            end
                            return;%don't start the Move CallBack
                    end
            end
            
            app.UIFigure.WindowButtonMotionFcn = {@app.MovePointCallback, hLine, index}; 
            app.UIFigure.WindowButtonUpFcn = {@app.ReleaseClickCallback};
        end

        function MovePointCallback(app, src, evt, line, i)
            %MOVEPOINTCALLBACK Summary of this function goes here
            %   Detailed explanation goes here
            
            %src is Figure Handle
            %evt is currently unused
            %line is the handle of the clicked line
            %       (pattern in y-edit mode OR breakpoint in x-edit mode)
            %
            % This callback is enabled by the LineClickCallBack and disabled
            % by the ReleaseClickCallback
            %
            %Note:
            %axis CurrentPoint reports non NaN values when outside of plot area
            %figure CurrentPoint reports values in figure pixels, not axis units
            %evt.IntersectionPoint reports NaN values when outside plot area
            
            if isequal(i, 'Slider')
                xc = round(app.UIAxes.CurrentPoint(1,1));
                xc = max(xc, 0);
                xc = min(xc, 255);
                line.XData = [xc xc];
                app.CommandValueEditField.Value = xc;
                return;
            elseif length(i)~=1
                return
            end
            
            switch app.editMode
                case 'y' %line selected is a pattern line
                    yc = round(app.UIAxes.CurrentPoint(1,2));
                    %limit yc between 0 and 255
                    yc = max(yc, 0);
                    yc = min(yc, 255);
                    %change YData for single Line
                    line.YData(i) = yc;
                    %disp('move point value')
                case 'x' %line selected is a breakpoint line
                    %disp('move breakpoint')
                    xc = round(app.UIAxes.CurrentPoint(1,1));
                    if i > 1 && i < length(app.Plots{1}.XData)
                        %limit xc between neighboring breakpoint values
                        xc = max(xc, app.Plots{1}.XData(i-1)+1);
                        xc = min(xc, app.Plots{1}.XData(i+1)-1);
                        %change XData for all patterns
                        for j=1:length(app.Plots)
                            app.Plots{j}.XData(i) = xc;
                        end
                        %change XData for breakpoint line
                        line.XData = [xc xc];
                    end
            end
        end
    
        function ReleaseClickCallback(app, src, evt )
            %RELEASECLICKCALLBACK called when mouse button is released
            % responsible for disabling the mouse movment callback, so it only occurs
            % while mouse button is pressed
            %
            % src is figure handle
            % evt is currently unused
            %
            % This callback is enabled by the LineClickCallBack and disabled
            % by the ReleaseClickCallback (itself)
            src.WindowButtonMotionFcn = [];
            src.WindowButtonUpFcn = [];
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            breakpoints = [0 100 200 255];
            nMaxBreakPoints=20;
            y = [100 20 40  50
                255 120 40 100
                30  40 50 70 ];
            nPatterns = size(y,1);
            cmdID = 1;
            group =2;
            
            
            app.UIAxes.Visible = 'off';
            hold(app.UIAxes,"on");
            
            app.Plots = cell(nPatterns,1);
            for i=1:nPatterns
                app.Plots{i} = plot(app.UIAxes, breakpoints, y(i,:));
            end
            
            %we will only change XData and YData in the callback functions so we need
            %to have all the handles to possible plots already assigned.  Since
            %breakpoints can be added and removed, we will already allocate all 20
            %possible breakpoints, and just use NaNs as x values to make them
            %invisible.  We won't draw vertical bars for the first and last breakpoints
            app.PlotBars = cell(nMaxBreakPoints-2,1);
            for i=2:nMaxBreakPoints-1
                if i<length(breakpoints)
                    x = [breakpoints(i) breakpoints(i)];
                else
                    x = [NaN NaN];
                end
                app.PlotBars{i-1} = plot(app.UIAxes, x, [0 255]);
            end
            app.PlotSlider = plot(app.UIAxes, [20 20], [0 255], 'LineWidth', 4, 'Color', [1, 0.5, 0]);
            hold(app.UIAxes,"off");
            
            axis(app.UIAxes, [0 255 0 255]);
            xlabel(app.UIAxes, ['Command (' num2str(cmdID) ')']);
            ylabel(app.UIAxes, 'Pulse Width (us)')
            title(app.UIAxes, ['Group' num2str(group)])
            app.UIAxes.XMinorTick = 'on';
            app.UIAxes.YMinorTick = 'on';
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.XMinorGrid = 'on';
            app.UIAxes.YMinorGrid = 'on';
            app.UIAxes.Toolbar.Visible = 'off';  %make the little toolbar at top right disappear.
            app.UIAxes.Interactions = [dataTipInteraction];
            

            for i=1:length(app.Plots)
                app.Plots{i}.ButtonDownFcn = {@app.LineClickCallback};
                app.Plots{i}.Marker = 'o';
                app.Plots{i}.MarkerFaceColor = app.Plots{i}.Color;
                app.Plots{i}.LineWidth = 2;
                %app.Plots{i}.HitTest = 'on';
            end
            for i=1:length(app.PlotBars)
                app.PlotBars{i}.ButtonDownFcn = {@app.LineClickCallback};
                app.PlotBars{i}.LineWidth = 2;
                app.PlotBars{i}.Color = 'k';
                %app.PlotBars{i}.LineStyle = ':';
                %app.PlotBars{i}.HitTest = 'on';
            end
            app.PlotSlider.ButtonDownFcn = {@app.SliderClickCallback};
            
            
            EditModeSwitchValueChanged(app, 0);
            ShowToolTipsCheckBoxValueChanged(app, 0);
            app.UIAxes.Visible = 'on';
        end

        % Value changed function: EditModeSwitch
        function EditModeSwitchValueChanged(app, event)
            if isequal(app.EditModeSwitch.Value, 'Breakpoints')
                disp('Edit X')
                app.editMode = 'x';
                for i=1:length(app.PlotBars)
                    app.PlotBars{i}.LineStyle = ':';
                    app.PlotBars{i}.HitTest = 'on'; 
                end
                for i=1:length(app.Plots)
                    app.Plots{i}.HitTest = 'on';
                end
            else
                disp('Edit Y')
                app.editMode = 'y';
                for i=1:length(app.PlotBars)
                    app.PlotBars{i}.LineStyle = 'none';
                    app.PlotBars{i}.HitTest = 'off';
                end
                for i=1:length(app.Plots)
                    app.Plots{i}.HitTest = 'on';
                end
            end
        end

        % Value changed function: ShowToolTipsCheckBox
        function ShowToolTipsCheckBoxValueChanged(app, event)
            if app.ShowToolTipsCheckBox.Value
                enableDefaultInteractivity(app.UIAxes);
            else
                disableDefaultInteractivity(app.UIAxes);
            end
            
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [filename, filepath] = uigetfile('.mat', 'Select Patterns File');
            if filename
                ld = load([filepath filename]);
            end
            app.PatternTable.Data = ld.Patterns;
            d = cell(size(ld.Patterns,1),1);
            for i= 1:size(ld.Patterns,1)
                d{i} = '';
            end
            app.ColorTable.Data = d;
            app.ColorTable.BackgroundColor = [1 0 0; 0 1 0; 0 0 1];
        end

        % Button pushed function: ButtonUp
        function ButtonUpPushed(app, event)
            app.PatternTable.Position(2) = max(app.PatternTable.Position(2)-100, -636);
            app.ColorTable.Position(2) = app.PatternTable.Position(2)
        end

        % Button pushed function: ButtonDown
        function ButtonDownPushed(app, event)
            app.PatternTable.Position(2) = min(app.PatternTable.Position(2)+100, 0);
            app.ColorTable.Position(2) = app.PatternTable.Position(2)
        end

        % Button pushed function: ButtonTop
        function ButtonTopPushed(app, event)
            app.PatternTable.Position(2) = -636;
            app.ColorTable.Position(2) = app.PatternTable.Position(2);
        end

        % Button pushed function: ButtonBottom
        function ButtonBottomPushed(app, event)
            app.PatternTable.Position(2) = 0;
            app.ColorTable.Position(2) = app.PatternTable.Position(2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Pattern Editor';
            app.FIleMenu = uimenu(app.UIFigure);
            app.FIleMenu.Text = 'FIle';
            app.OpenMenu = uimenu(app.FIleMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FIleMenu);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FIleMenu);
            app.SaveAsMenu.Text = 'Save As';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.Visible = 'off';
            app.EditModeSwitchLabel = uilabel(app.UIFigure);
            app.EditModeSwitchLabel.Text = 'Edit Mode';
            app.EditModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.EditModeSwitch.Items = {'Pattern Values', 'Breakpoints'};
            app.EditModeSwitch.ValueChangedFcn = createCallbackFcn(app, @EditModeSwitchValueChanged, true);
            app.EditModeSwitch.Value = 'Pattern Values';
            app.ShowToolTipsCheckBox = uicheckbox(app.UIFigure);
            app.ShowToolTipsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowToolTipsCheckBoxValueChanged, true);
            app.ShowToolTipsCheckBox.Text = 'Show ToolTips';
            app.ViewModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.ViewModeSwitch.Items = {'Amplitude', 'Pulse Width'};
            app.ViewModeSwitch.Value = 'Pulse Width';
            app.StimulationPanel = uipanel(app.UIFigure);
            app.StimulationPanel.Title = 'Stimulation';
            app.StimONButton = uibutton(app.StimulationPanel, 'push');
            app.StimONButton.Text = 'Stim ON';
            app.StimOFFButton = uibutton(app.StimulationPanel, 'push');
            app.StimOFFButton.Text = 'Stim OFF';
            app.StimModeSwitchLabel = uilabel(app.StimulationPanel);
            app.StimModeSwitchLabel.Text = 'Stim Mode';
            app.StimModeSwitch = uiswitch(app.StimulationPanel, 'slider');
            app.StimModeSwitch.Items = {'Downloaded', 'Displayed'};
            app.StimModeSwitch.Value = 'Downloaded';
            app.HighBandwidthUsePDOsCheckBox = uicheckbox(app.StimulationPanel);
            app.HighBandwidthUsePDOsCheckBox.Text = {'High Bandwidth'; '(Use PDOs)'};
            app.CommandValueEditFieldLabel = uilabel(app.StimulationPanel);
            app.CommandValueEditFieldLabel.Text = 'Command Value';
            app.CommandValueEditField = uieditfield(app.StimulationPanel, 'numeric');
            app.CommandValueEditField.RoundFractionalValues = 'on';
            app.PatternDropDownLabel = uilabel(app.UIFigure);
            app.PatternDropDownLabel.Text = 'Pattern';
            app.PatternDropDown = uidropdown(app.UIFigure);
            app.ModuleDropDownLabel = uilabel(app.UIFigure);
            app.ModuleDropDownLabel.Text = 'Module';
            app.ModuleDropDown = uidropdown(app.UIFigure);
            app.SystemPatternsPanel = uipanel(app.UIFigure);
            app.SystemPatternsPanel.Title = 'System Patterns';
            app.WritetoModulesButton = uibutton(app.SystemPatternsPanel, 'push');
            app.WritetoModulesButton.Text = 'Write to Module(s)';
            app.ReadfromModulesButton = uibutton(app.SystemPatternsPanel, 'push');
            app.ReadfromModulesButton.Text = 'Read from Module(s)';
            app.CompareModulesButton = uibutton(app.SystemPatternsPanel, 'push');
            app.CompareModulesButton.Text = 'Compare Module(s)';
            app.CurrentModuleOnlyCheckBox = uicheckbox(app.SystemPatternsPanel);
            app.CurrentModuleOnlyCheckBox.Text = 'Current Module Only';
            app.Panel = uipanel(app.UIFigure);
            app.PatternTable = uitable(app.Panel);
            app.PatternTable.ColumnName = {''};
            app.PatternTable.RowName = {};
            app.ColorTable = uitable(app.Panel);
            app.ColorTable.ColumnName = {''};
            app.ColorTable.RowName = {};
            app.PatternTable_2 = uitable(app.Panel);
            app.PatternTable_2.ColumnName = {'Pattern'; 'Node'; 'Ch'; 'Min'; 'Max'; 'Show'; 'Mute'; 'PW'; 'Amp'; 'Muscle'; 'Select'};
            app.PatternTable_2.RowName = {};
            app.ButtonUp = uibutton(app.UIFigure, 'push');
            app.ButtonUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonUpPushed, true);
            app.ButtonUp.Text = '▲';
            app.ButtonDown = uibutton(app.UIFigure, 'push');
            app.ButtonDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonDownPushed, true);
            app.ButtonDown.Text = '▼';
            app.ButtonTop = uibutton(app.UIFigure, 'push');
            app.ButtonTop.ButtonPushedFcn = createCallbackFcn(app, @ButtonTopPushed, true);
            app.ButtonTop.Text = {'▬'; '▲'};
            app.ButtonBottom = uibutton(app.UIFigure, 'push');
            app.ButtonBottom.ButtonPushedFcn = createCallbackFcn(app, @ButtonBottomPushed, true);
            app.ButtonBottom.Text = {'▼'; '▬'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = patternedit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end